classdef uno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        updatecardButton    matlab.ui.control.Button
        cardEditField       matlab.ui.control.EditField
        cardEditFieldLabel  matlab.ui.control.Label
        YourHandPanel       matlab.ui.container.Panel
        Image               matlab.ui.control.Image
    end

    
    properties (Access = private)
        numPlayers;
        playerTurn;
        topCard;
        playersNumCards;
        myPlayerNum;
        channelID = 2364789;
        writeKey = 'I2UG9UCL0S6X3SCM';
        readKey = 'MJ8791RFGBHXVTG1';

    end
    
    properties (Access = public)
        deck;
        player;% Description
    end
    
    methods (Access = private)
        function [] = updateturn(app)
            %When calling this function provide both the current turn number from app
            %as well as the totalplayer number also from app
            if app.playerTurn < app.numPlayers
              app.playerTurn = app.playerTurn + 1;
            else
                app.playerTurn = 1;
            end
        end
        
        function writeData(app, timestamp)
            loop = true;
            while loop
                if (datetime("now") - timestamp) > duration(0,0,20)
                    thingSpeakWrite(app.channelID,[app.numPlayers, app.playerTurn,...
                        app.topCard,app.playersNumCards(1),app.playersNumCards(2),...
                        app.playersNumCards(3),app.playersNumCards(4)],...
                        "WriteKey",app.writeKey,"Timestamp",datetime("now"));
                    loop = false;
                else
                    pause(2);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [data,timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
            numberplayers = data(1);
            playerturn = data(2);
            app.deck = Deck();
            app.player = Player(app.deck);
            if (numberplayers >= 4) | (playerturn > 1)
                %If game has started or if there are 4 or more people do
                %not allow player to join game
                delete(app.UIFigure)
                return
            end
            app.numPlayers = numberplayers + 1;
            app.myPlayerNum = numberplayers + 1;
            app.playerTurn = playerturn;
            app.topCard = data(3);
            app.playersNumCards = [data(4), data(5), data(6), data(7)];
            app.playersNumCards(app.myPlayerNum) = app.player.playerHandLength;
            if numberplayers == 0
                card = app.deck.pullCard();
                app.topCard = card.encodedCardNumber;
            end

            app.writeData(timestamp)
        end

        % Button pushed function: updatecardButton
        function updatecardButtonPushed(app, event)
            pickedCard = app.deck.pullCard();


            app.Image.ImageSource = pickedCard.imageSource;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YourHandPanel = uipanel(app.UIFigure);
            app.YourHandPanel.Title = 'Your Hand';
            app.Image = uiimage(app.YourHandPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'blue0.png');
            app.cardEditFieldLabel = uilabel(app.UIFigure);
            app.cardEditFieldLabel.Text = 'card';
            app.cardEditField = uieditfield(app.UIFigure, 'text');
            app.updatecardButton = uibutton(app.UIFigure, 'push');
            app.updatecardButton.ButtonPushedFcn = createCallbackFcn(app, @updatecardButtonPushed, true);
            app.updatecardButton.Text = 'update card';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end